classdef AppDesign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ChnBiTpMunTnhLabel             matlab.ui.control.Label
        NhphEditField                  matlab.ui.control.EditField
        NhphEditFieldLabel             matlab.ui.control.Label
        NhpgitrnisuyxEditField         matlab.ui.control.EditField
        NhpgitrnisuyxEditFieldLabel    matlab.ui.control.Label
        NhpMngByaEditField             matlab.ui.control.EditField
        NhpMngByaEditFieldLabel        matlab.ui.control.Label
        NhpMngAxaEditField             matlab.ui.control.EditField
        NhpMngAxaEditFieldLabel        matlab.ui.control.Label
        BTChng1112Button               matlab.ui.control.Button
        BTChng10Button                 matlab.ui.control.Button
        BTChng9Button                  matlab.ui.control.Button
        BTChng8Button                  matlab.ui.control.Button
        HmbinixtheofxkhisdngPPlpEditField  matlab.ui.control.EditField
        HmbinixtheofxkhisdngPPlpLabel  matlab.ui.control.Label
        KtqutnhtonTextArea             matlab.ui.control.TextArea
        KtqutnhtonTextAreaLabel        matlab.ui.control.Label
        PPxlDropDown                   matlab.ui.control.DropDown
        PPxlDropDownLabel              matlab.ui.control.Label
        RUNButton                      matlab.ui.control.Button
        GiihnabsolanlapEditField       matlab.ui.control.EditField
        GiihnabsolanlapEditFieldLabel  matlab.ui.control.Label
        HmhocdliucnxlEditField         matlab.ui.control.EditField
        HmhocdliucnxlEditFieldLabel    matlab.ui.control.Label
        Lp21DTV1HtnSVNguynonNguynKhangMsSV21200106Label  matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        MnhcPhngphptnhvMatlabLabel     matlab.ui.control.Label
        BOCOGIAKLabel                  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PPxlDropDown
        function PPxlDropDownValueChanged(app, event)
            value = app.PPxlDropDown.Value;
            if strcmp(value, 'Lặp')
                app.HmbinixtheofxkhisdngPPlpEditField.Visible='on';
            elseif strcmp(value, 'Tiếp tuyến')
                app.HmbinixtheofxkhisdngPPlpEditField.Visible='off';
            end
        end

        % Button pushed function: BTChng8Button
        function BTChng8ButtonPushed(app, event)

            data1_8=num2str('sqrt(1.65)*x^3+1.12*x^2+14.35*x-12.75');
            data2_8=num2str('[0.5 1.5 3]');
            % Load dữ liệu cho app.HmhocdliucnxlEditField
            app.HmhocdliucnxlEditField.Value = data1_8;

            % Load dữ liệu cho app.GiihnabsolanlapEditField
            app.GiihnabsolanlapEditField.Value = data2_8;

            app.UIAxes.Visible='on';
            app.PPxlDropDown.Visible='on';
            app.HmhocdliucnxlEditField.Visible='on';
            app.GiihnabsolanlapEditField.Visible='on';
            app.NhpMngAxaEditField.Visible='off';
            app.NhpMngByaEditField.Visible='off';
            app.NhpgitrnisuyxEditField.Visible='off';
            app.NhphEditField.Visible='off';   

        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)

            %********** Chuong 8 **********
            if (app.HmhocdliucnxlEditField.Visible==1 && app.GiihnabsolanlapEditField.Visible==1)
                try
                    sym x;
                    f = str2func(['@(x)' char(app.HmhocdliucnxlEditField.Value)]);
                    flap = str2func(['@(x)' char(app.HmbinixtheofxkhisdngPPlpEditField.Value)]);
                    %gioihan = app.GiihnabsolanlapEditField.Value;
                    gh = str2num(app.GiihnabsolanlapEditField.Value);

                    if isempty(gh) || numel(gh) ~= 3
                        %Kiểm tra số phần tử có trong 'Giới hạn [a b solanlap]'
                        errordlg('Phải nhập đủ 3 thành phần [a, b, solanlap]. Vui lòng kiểm tra và nhập lại.', 'Lỗi');
                    else
                        a = gh(1,1);
                        b = gh(1,2);
                        solanlap = gh(1,3);

                        x_8 = linspace(a,b,40);
                        for i=1:40
                            y_8(i) = f(x_8(i));
                        end
                        y_8;

                        if (strcmp(app.PPxlDropDown.Value, 'Lặp'))
                            [nghiem] = lap(f,flap,a,b,solanlap);
                            app.KtqutnhtonTextArea.Value=num2str(nghiem);
                            plot(app.UIAxes, x_8, y_8, '-*r');
                        elseif (strcmp(app.PPxlDropDown.Value, 'Tiếp tuyến'))
                            [nghiem] = tieptuyen(f,a,b,solanlap);
                            app.KtqutnhtonTextArea.Value=num2str(nghiem);
                            plot(app.UIAxes, x_8, y_8, '-og');
                        end
                    end
                catch
                    % Nếu có lỗi khi nhập liệu, hiển thị hộp thoại thông báo lỗi
                    errordlg('Dữ liệu không hợp lệ. Vui lòng kiểm tra và nhập lại.', 'Lỗi');
                end

            %********** Chuong 11+12 **********
            elseif (app.NhphEditField.Visible == 1)
                try
                    xa_1112 = str2num(app.NhpMngAxaEditField.Value);
                    ya_1112 = str2num(app.NhpMngByaEditField.Value);
                    gtns_x_1112 = str2num(app.NhpgitrnisuyxEditField.Value);
                    gt_h_1112 = str2num(app.NhphEditField.Value);
                    if    ~isreal(gtns_x_1112) || ~isreal(gt_h_1112) || isempty(gtns_x_1112) || isempty(gt_h_1112) || numel(gtns_x_1112) ~= 1 || numel(gt_h_1112) ~= 1
                        %Kiểm tra giá trị của x và h có phải là số thực 
                        errordlg('Giá trị của x or h phải là số thực duy nhất. Vui lòng kiểm tra và nhập lại.', 'Lỗi');
                    else
                        [result_1112] = noisuy(xa_1112, ya_1112, gtns_x_1112, gt_h_1112);
                        app.KtqutnhtonTextArea.Value = num2str(result_1112);
                    end
                catch
                     % Nếu có lỗi khi nhập liệu, hiển thị hộp thoại thông báo lỗi
                    errordlg('Dữ liệu không hợp lệ. Vui lòng kiểm tra và nhập lại.', 'Lỗi');
                end

            %********** Chuong 10 **********
            elseif (app.NhpgitrnisuyxEditField.Visible==1 )
                try
                    xa_10 = str2num(app.NhpMngAxaEditField.Value);
                    ya_10 = str2num(app.NhpMngByaEditField.Value);
                    gtns_x_10 = str2num(app.NhpgitrnisuyxEditField.Value);   
                    if    isempty(gtns_x_10) || ~isreal(gtns_x_10) || numel(gtns_x_10) ~= 1
                        errordlg('Giá trị của x phải là một số thực duy nhất. Vui lòng kiểm tra và nhập lại.', 'Lỗi');
                    else
                        [result] = noisuy_newton(xa_10, ya_10, gtns_x_10);
                        app.KtqutnhtonTextArea.Value = num2str(result);
                    end
                    
                catch
                     % Nếu có lỗi khi nhập liệu, hiển thị hộp thoại thông báo lỗi
                    errordlg('Dữ liệu không hợp lệ. Vui lòng kiểm tra và nhập lại.', 'Lỗi');
                end
            %********** Chuong 9 **********
             elseif (app.NhpMngAxaEditField.Visible == 1 && app.NhpMngByaEditField.Visible == 1)
                try
                    A = str2num(app.NhpMngAxaEditField.Value);
                    b = str2num(app.NhpMngByaEditField.Value);
                    [x] = cholesky(A, b);
                    % Tạo chuỗi định dạng "[a1; a2; a3; ...]"
                    xString = sprintf('[%s ]', strjoin(arrayfun(@num2str, x, 'UniformOutput', false), '; '));
                    app.KtqutnhtonTextArea.Value = xString;
                catch
                     % Nếu có lỗi khi nhập liệu, hiển thị hộp thoại thông báo lỗi
                    errordlg('Dữ liệu không hợp lệ. Vui lòng kiểm tra và nhập lại.', 'Lỗi');
                end
            end

        end

        % Button pushed function: BTChng9Button
        function BTChng9ButtonPushed(app, event)
            
            dataA_9=num2str('[4.9 1.0 0.1 1.1; 1.0 6.4 1.2 0.2; 0.1 1.2 3.6 1.1; 1.1 0.2 1.1 6.4]');
            dataB_9=num2str('[5.0; 2.2; 3.7; 2.2]');
            % Load dữ liệu 
            app.NhpMngAxaEditField.Value = dataA_9;
            app.NhpMngByaEditField.Value = dataB_9;
            
            %Ẩn/hiện các ô nhập dữ liệu phù hợp theo từng chương 
            app.UIAxes.Visible='off';
            app.PPxlDropDown.Visible='off';
            app.HmhocdliucnxlEditField.Visible='off';
            app.GiihnabsolanlapEditField.Visible='off';
            app.NhpMngAxaEditField.Visible='on';
            app.NhpMngByaEditField.Visible='on';
            app.NhpgitrnisuyxEditField.Visible='off';
            app.NhphEditField.Visible='off';
            app.HmbinixtheofxkhisdngPPlpEditField.Visible='off';

            plot(app.UIAxes, 0, 0);     %Xóa đồ thị

        end

        % Button pushed function: BTChng10Button
        function BTChng10ButtonPushed(app, event)

            dataxa_10=num2str('[6.3 6.72 7.14 7.56 7.98 8.4]');
            dataya_10=num2str('[21.4259 23.377 25.3622 27.3831 29.438 31.5253]');
            datax_10=num2str('6.51');
            % Load dữ liệu 
            app.NhpMngAxaEditField.Value = dataxa_10;
            app.NhpMngByaEditField.Value = dataya_10;
            app.NhpgitrnisuyxEditField.Value = datax_10;

            %Ẩn/hiện các ô nhập dữ liệu phù hợp theo từng chương 
            app.UIAxes.Visible='off';
            app.PPxlDropDown.Visible='off';
            app.HmhocdliucnxlEditField.Visible='off';
            app.GiihnabsolanlapEditField.Visible='off';
            app.NhpMngAxaEditField.Visible='on';
            app.NhpMngByaEditField.Visible='on';
            app.NhpgitrnisuyxEditField.Visible='on';
            app.NhphEditField.Visible='off';
            app.HmbinixtheofxkhisdngPPlpEditField.Visible='off';

            plot(app.UIAxes, 0, 0); %Xóa đồ thị
        end

        % Button pushed function: BTChng1112Button
        function BTChng1112ButtonPushed(app, event)
            dataxa_1112=num2str('[0.1 0.3 0.5 0.7 0.9]');
            dataya_1112=num2str('[0.1002 0.3047 0.5236 0.7754 1.1198]');
            datax_1112=num2str('0.7');
            datah_1112=num2str('0.2')
            % Load dữ liệu 
            app.NhpMngAxaEditField.Value = dataxa_1112;
            app.NhpMngByaEditField.Value = dataya_1112;
            app.NhpgitrnisuyxEditField.Value = datax_1112;
            app.NhphEditField.Value = datah_1112;
            
            %Ẩn/hiện các ô nhập dữ liệu phù hợp theo từng chương 
            app.UIAxes.Visible='off';
            app.PPxlDropDown.Visible='off';
            app.HmhocdliucnxlEditField.Visible='off';
            app.GiihnabsolanlapEditField.Visible='off';
            app.NhpMngAxaEditField.Visible='on';
            app.NhpMngByaEditField.Visible='on';
            app.NhpgitrnisuyxEditField.Visible='on';
            app.NhphEditField.Visible='on';
            app.HmbinixtheofxkhisdngPPlpEditField.Visible='off';

            plot(app.UIAxes, 0, 0);     %Xóa đồ thị
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Đồ THị')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.BOCOGIAKLabel = uilabel(app.UIFigure);
            app.BOCOGIAKLabel.Text = 'BÁO CÁO GIỮA KỲ';
            app.MnhcPhngphptnhvMatlabLabel = uilabel(app.UIFigure);
            app.MnhcPhngphptnhvMatlabLabel.Text = 'Môn học: Phương pháp tính và Matlab';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo-khoa-hoc-tu-nhien-inkythuatso-01.jpg');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'avt.jpg');
            app.Lp21DTV1HtnSVNguynonNguynKhangMsSV21200106Label = uilabel(app.UIFigure);
            app.Lp21DTV1HtnSVNguynonNguynKhangMsSV21200106Label.Text = {'Lớp: 21DTV1'; 'Họ tên SV: Nguyễn Đoàn Nguyên Khang '; 'Mã số SV: 21200106 '; ''};
            app.HmhocdliucnxlEditFieldLabel = uilabel(app.UIFigure);
            app.HmhocdliucnxlEditFieldLabel.Text = 'Hàm hoặc dữ liệu cần xử lý ';
            app.HmhocdliucnxlEditField = uieditfield(app.UIFigure, 'text');
            app.HmhocdliucnxlEditField.Visible = 'off';
            app.GiihnabsolanlapEditFieldLabel = uilabel(app.UIFigure);
            app.GiihnabsolanlapEditFieldLabel.Text = 'Giới hạn [a b solanlap]';
            app.GiihnabsolanlapEditField = uieditfield(app.UIFigure, 'text');
            app.GiihnabsolanlapEditField.Visible = 'off';
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Icon = fullfile(pathToMLAPP, 'buttton.jpg');
            app.RUNButton.Text = 'RUN';
            app.PPxlDropDownLabel = uilabel(app.UIFigure);
            app.PPxlDropDownLabel.Text = 'PP xử lý ';
            app.PPxlDropDown = uidropdown(app.UIFigure);
            app.PPxlDropDown.Items = {'Tiếp tuyến', 'Lặp'};
            app.PPxlDropDown.ValueChangedFcn = createCallbackFcn(app, @PPxlDropDownValueChanged, true);
            app.PPxlDropDown.Visible = 'off';
            app.PPxlDropDown.Value = 'Tiếp tuyến';
            app.KtqutnhtonTextAreaLabel = uilabel(app.UIFigure);
            app.KtqutnhtonTextAreaLabel.Text = 'Kết quả tính toán ';
            app.KtqutnhtonTextArea = uitextarea(app.UIFigure);
            app.KtqutnhtonTextArea.Editable = 'off';
            app.HmbinixtheofxkhisdngPPlpLabel = uilabel(app.UIFigure);
            app.HmbinixtheofxkhisdngPPlpLabel.Text = {'Hàm biến đổi x theo f(x)'; 'khi sử dụng PP lặp '};
            app.HmbinixtheofxkhisdngPPlpEditField = uieditfield(app.UIFigure, 'text');
            app.HmbinixtheofxkhisdngPPlpEditField.Editable = 'off';
            app.HmbinixtheofxkhisdngPPlpEditField.Visible = 'off';
            app.HmbinixtheofxkhisdngPPlpEditField.Value = '(sqrt(1.65)*x^3+1.12*x^2-12.75)/(-14.35)';
            app.BTChng8Button = uibutton(app.UIFigure, 'push');
            app.BTChng8Button.ButtonPushedFcn = createCallbackFcn(app, @BTChng8ButtonPushed, true);
            app.BTChng8Button.Text = 'BT Chương 8';
            app.BTChng9Button = uibutton(app.UIFigure, 'push');
            app.BTChng9Button.ButtonPushedFcn = createCallbackFcn(app, @BTChng9ButtonPushed, true);
            app.BTChng9Button.Text = 'BT Chương 9';
            app.BTChng10Button = uibutton(app.UIFigure, 'push');
            app.BTChng10Button.ButtonPushedFcn = createCallbackFcn(app, @BTChng10ButtonPushed, true);
            app.BTChng10Button.Text = 'BT Chương 10';
            app.BTChng1112Button = uibutton(app.UIFigure, 'push');
            app.BTChng1112Button.ButtonPushedFcn = createCallbackFcn(app, @BTChng1112ButtonPushed, true);
            app.BTChng1112Button.Text = 'BT Chương 11+12';
            app.NhpMngAxaEditFieldLabel = uilabel(app.UIFigure);
            app.NhpMngAxaEditFieldLabel.Text = 'Nhập Mảng A/xa ';
            app.NhpMngAxaEditField = uieditfield(app.UIFigure, 'text');
            app.NhpMngAxaEditField.Visible = 'off';
            app.NhpMngByaEditFieldLabel = uilabel(app.UIFigure);
            app.NhpMngByaEditFieldLabel.Text = 'Nhập Mảng B/ya ';
            app.NhpMngByaEditField = uieditfield(app.UIFigure, 'text');
            app.NhpMngByaEditField.Visible = 'off';
            app.NhpgitrnisuyxEditFieldLabel = uilabel(app.UIFigure);
            app.NhpgitrnisuyxEditFieldLabel.Text = 'Nhập giá trị nội suy  x ';
            app.NhpgitrnisuyxEditField = uieditfield(app.UIFigure, 'text');
            app.NhpgitrnisuyxEditField.Visible = 'off';
            app.NhphEditFieldLabel = uilabel(app.UIFigure);
            app.NhphEditFieldLabel.Text = 'Nhập h ';
            app.NhphEditField = uieditfield(app.UIFigure, 'text');
            app.NhphEditField.Visible = 'off';
            app.ChnBiTpMunTnhLabel = uilabel(app.UIFigure);
            app.ChnBiTpMunTnhLabel.Text = 'Chọn Bài Tập Muốn Tính';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppDesign

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end